classdef battle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        Tab_7                  matlab.ui.container.Tab
        Image                  matlab.ui.control.Image
        Label_6                matlab.ui.control.Label
        byLabel                matlab.ui.control.Label
        Tab                    matlab.ui.container.Tab
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        XPanel                 matlab.ui.container.Panel
        Label                  matlab.ui.control.Label
        EditField_x_init       matlab.ui.control.NumericEditField
        rxEditFieldLabel       matlab.ui.control.Label
        rxEditField            matlab.ui.control.NumericEditField
        pxEditFieldLabel       matlab.ui.control.Label
        pxEditField            matlab.ui.control.NumericEditField
        uEditFieldLabel        matlab.ui.control.Label
        uEditField             matlab.ui.control.NumericEditField
        alphaEditFieldLabel    matlab.ui.control.Label
        alphaEditField         matlab.ui.control.NumericEditField
        YPanel                 matlab.ui.container.Panel
        EditField_5Label       matlab.ui.control.Label
        EditField_y_init       matlab.ui.control.NumericEditField
        ryEditFieldLabel       matlab.ui.control.Label
        ryEditField            matlab.ui.control.NumericEditField
        pyEditFieldLabel       matlab.ui.control.Label
        pyEditField            matlab.ui.control.NumericEditField
        vEditFieldLabel        matlab.ui.control.Label
        vEditField             matlab.ui.control.NumericEditField
        betaEditFieldLabel     matlab.ui.control.Label
        betaEditField          matlab.ui.control.NumericEditField
        Button                 matlab.ui.control.Button
        Panel                  matlab.ui.container.Panel
        TEditFieldLabel        matlab.ui.control.Label
        TEditField             matlab.ui.control.NumericEditField
        ButtonGroup            matlab.ui.container.ButtonGroup
        XButton                matlab.ui.control.RadioButton
        YButton                matlab.ui.control.RadioButton
        Button_2               matlab.ui.control.RadioButton
        Tab_2                  matlab.ui.container.Tab
        UIAxes_2               matlab.ui.control.UIAxes
        UIAxes2_2              matlab.ui.control.UIAxes
        XPanel_2               matlab.ui.container.Panel
        Label_2                matlab.ui.control.Label
        EditField_x_init_2     matlab.ui.control.NumericEditField
        rxEditField_2Label     matlab.ui.control.Label
        rxEditField_2          matlab.ui.control.NumericEditField
        uEditField_2Label      matlab.ui.control.Label
        uEditField_2           matlab.ui.control.NumericEditField
        alphaEditField_2Label  matlab.ui.control.Label
        alphaEditField_2       matlab.ui.control.NumericEditField
        srxEditFieldLabel      matlab.ui.control.Label
        srxEditField           matlab.ui.control.NumericEditField
        sxEditFieldLabel       matlab.ui.control.Label
        sxEditField            matlab.ui.control.NumericEditField
        YPanel_2               matlab.ui.container.Panel
        EditField_5Label_2     matlab.ui.control.Label
        EditField_y_init_2     matlab.ui.control.NumericEditField
        ryEditField_2Label     matlab.ui.control.Label
        ryEditField_2          matlab.ui.control.NumericEditField
        vEditField_2Label      matlab.ui.control.Label
        vEditField_2           matlab.ui.control.NumericEditField
        betaEditField_2Label   matlab.ui.control.Label
        betaEditField_2        matlab.ui.control.NumericEditField
        sryEditFieldLabel      matlab.ui.control.Label
        sryEditField           matlab.ui.control.NumericEditField
        syEditFieldLabel       matlab.ui.control.Label
        syEditField            matlab.ui.control.NumericEditField
        Button_3               matlab.ui.control.Button
        Panel_2                matlab.ui.container.Panel
        TEditField_2Label      matlab.ui.control.Label
        TEditField_2           matlab.ui.control.NumericEditField
        ButtonGroup_2          matlab.ui.container.ButtonGroup
        XButton_2              matlab.ui.control.RadioButton
        YButton_2              matlab.ui.control.RadioButton
        Button_4               matlab.ui.control.RadioButton
        Tab_3                  matlab.ui.container.Tab
        UIAxes_3               matlab.ui.control.UIAxes
        UIAxes2_3              matlab.ui.control.UIAxes
        YPanel_3               matlab.ui.container.Panel
        EditField_5Label_3     matlab.ui.control.Label
        EditField_y_init_3     matlab.ui.control.NumericEditField
        ryEditField_3Label     matlab.ui.control.Label
        ryEditField_3          matlab.ui.control.NumericEditField
        vEditField_3Label      matlab.ui.control.Label
        vEditField_3           matlab.ui.control.NumericEditField
        betaEditField_3Label   matlab.ui.control.Label
        betaEditField_3        matlab.ui.control.NumericEditField
        syEditField_2Label     matlab.ui.control.Label
        syEditField_2          matlab.ui.control.NumericEditField
        pyEditField_2Label     matlab.ui.control.Label
        pyEditField_2          matlab.ui.control.NumericEditField
        Button_5               matlab.ui.control.Button
        Panel_3                matlab.ui.container.Panel
        TEditField_3Label      matlab.ui.control.Label
        TEditField_3           matlab.ui.control.NumericEditField
        ButtonGroup_3          matlab.ui.container.ButtonGroup
        XButton_3              matlab.ui.control.RadioButton
        YButton_3              matlab.ui.control.RadioButton
        Button_6               matlab.ui.control.RadioButton
        XPanel_3               matlab.ui.container.Panel
        Label_3                matlab.ui.control.Label
        EditField_x_init_3     matlab.ui.control.NumericEditField
        rxEditField_3Label     matlab.ui.control.Label
        rxEditField_3          matlab.ui.control.NumericEditField
        uEditField_3Label      matlab.ui.control.Label
        uEditField_3           matlab.ui.control.NumericEditField
        alphaEditField_3Label  matlab.ui.control.Label
        alphaEditField_3       matlab.ui.control.NumericEditField
        srxEditField_2Label    matlab.ui.control.Label
        srxEditField_2         matlab.ui.control.NumericEditField
        Tab_4                  matlab.ui.container.Tab
        UIAxes4                matlab.ui.control.UIAxes
        Button_7               matlab.ui.control.Button
        UIAxes5                matlab.ui.control.UIAxes
        UIAxes6                matlab.ui.control.UIAxes
        EditField              matlab.ui.control.EditField
        Label_4                matlab.ui.control.Label
        Button_8               matlab.ui.control.Button
        TextArea_2             matlab.ui.control.TextArea
        Tab_6                  matlab.ui.container.Tab
        UIAxes4_2              matlab.ui.control.UIAxes
        Button_9               matlab.ui.control.Button
        UIAxes5_2              matlab.ui.control.UIAxes
        UIAxes6_2              matlab.ui.control.UIAxes
        EditField_4            matlab.ui.control.EditField
        Label_5                matlab.ui.control.Label
        Button_10              matlab.ui.control.Button
        TextArea               matlab.ui.control.TextArea
    end

    
    methods (Access = private)
        
        function results = LHD(app,x,y)
            
            %画硫磺岛的动态战局示意图的函数
            
            A=size(x);          %得到x数组的大小
            file='LHD.jpg';     
            
            I=imread(file);     %读取显示硫磺岛的地图
            imshow(I,'Parent',app.UIAxes4);  %显示硫磺岛的地图
            
            hold on

            x_axis=[0.35*800,0.65*800]';    %美军的圈的坐标
            y_axis=[0.65*1128,0.35*1128]';  %日军的圈的坐标
            s =[0.15*1.414,0.15*1.414]';    %圈的大小，保证两个圈相切
            t= 0:pi/10:2*pi;
            
            %画出美军的圈
            pb(1)=patch(app.UIAxes4,(s(1)*sin(t)*800+ x_axis(1)),(s(1)*cos(t)*1128+y_axis(1)),'b','edgecolor','k');
            alpha(pb(1),.3);    %透明度为0.3
            
            %画出日军的圈
            pb(2)=patch(app.UIAxes4,(s(2)*sin(t)*800+ x_axis(2)),(s(2)*cos(t)*1128+y_axis(2)),'y','edgecolor','k');
            alpha(pb(2),.3);    %透明度为0.3
            
            close() %关掉不必要的界面
            
            for i=1:(A(2))
                delete(pb)   %删去椭圆
                A_size=2*x(i)/(x(i)+y(i));  %根据美军和日军的人数确定美军圈的大小
                J_size=2*y(i)/(x(i)+y(i));  %根据美军和日军的人数确定日军圈的大小
                
                %画出美军的圈
                pb(1)=patch(app.UIAxes4,(A_size*s(1)*sin(t)*800+ x_axis(1)),(A_size*s(1)*cos(t)*1128+y_axis(1)),'b','edgecolor','k');
                alpha(pb(1),.3);
                
                %画出日军的圈
                pb(2)=patch(app.UIAxes4,(J_size*s(2)*sin(t)*800+ x_axis(2)),(J_size*s(2)*cos(t)*1128+y_axis(2)),'y','edgecolor','k');
                alpha(pb(2),.3);
                
                axis(app.UIAxes4,[0 800 0 1128])    %设置坐标轴的参数，防止坐标轴变大变小
                pause(0.1);     %等待0.1s
                
                %显示仿真当前时刻
                num2str(double(i/10))
                S="T="+num2str(double(i)/10);
                app.EditField.Value=S;
                
                %当仿真到达一定时刻后，停止仿真
                if i==60
                    break
                end
                
            end
        end
        
        function results = YZ(app,x,y)
            
            %画德浪河谷的动态战局示意图的函数
            
            A=size(x);  %得到x数组的大小
            file='YZ.jpg';  %
            
            I=imread(file);
            imshow(I,'Parent',app.UIAxes4_2);
            
            hold on

            x_axis=[0.35*673,0.65*673]';
            y_axis=[0.65*869,0.35*869]';
            s =[0.15*1.414,0.15*1.414]';
            t= 0:pi/10:2*pi;
            
            
            pb(1)=patch(app.UIAxes4_2,(s(1)*sin(t)*673+ x_axis(1)),(s(1)*cos(t)*869+y_axis(1)),'b','edgecolor','k');
            alpha(pb(1),.3);
            
            pb(2)=patch(app.UIAxes4_2,(s(2)*sin(t)*673+ x_axis(2)),(s(2)*cos(t)*869+y_axis(2)),'r','edgecolor','k');
            alpha(pb(2),.3);
            
            close()
            %delete(pb)   %删去椭圆
            
            for i=1:(A(2))
                delete(pb)   %删去椭圆
                A_size=2*x(i)/(x(i)+y(i));
                J_size=2*y(i)/(x(i)+y(i));
                pb(1)=patch(app.UIAxes4_2,(A_size*s(1)*sin(t)*673+ x_axis(1)),(A_size*s(1)*cos(t)*869+y_axis(1)),'b','edgecolor','k');
                alpha(pb(1),.3);
                
                pb(2)=patch(app.UIAxes4_2,(J_size*s(2)*sin(t)*673+ x_axis(2)),(J_size*s(2)*cos(t)*869+y_axis(2)),'r','edgecolor','k');
                alpha(pb(2),.3);
                axis(app.UIAxes4,[0 673 0 869])
                pause(0.1);
                num2str(double(i/10))
                S="T="+num2str(double(i)/10);
                app.EditField_4.Value=S;
                
                if i==60
                    break
                end
                
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            file='LHD.jpg';
            
            I=imread(file);
            imshow(I,'Parent',app.UIAxes4);


            file='YZ.jpg';
            
            I=imread(file);
            imshow(I,'Parent',app.UIAxes4_2);


        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % 参数定义
            x_init=app.EditField_x_init.Value; %x的初始士兵数
            y_init=app.EditField_y_init.Value;  %y的初始士兵数
            test_num=app.TEditField.Value;%最大仿真次数
            rx=app.rxEditField.Value;       %x方射击率（每个士兵单位时间内的射击次数）
            ry=app.ryEditField.Value;        %y方射击率（每个士兵单位时间内的射击次数）
            px=app.pxEditField.Value;     %x方的每次射击的命中率
            py=app.pyEditField.Value;     %y方的每次射击的命中率
            a=ry*py;      %y方战斗有效系数
            b=rx*px;      %x方战斗有效系数
            u=app.uEditField.Value;          %单位时间内x方的增援人数
            v=app.vEditField.Value;          %单位时间内y方的增援人数
            alpha=app.alphaEditField.Value;  %x方的非战斗减员率
            beta=app.betaEditField.Value;   %y方的非战斗减员率
            dx=0;         %x方人数变化量
            dy=0;         %x方人数变化量
            x=zeros(test_num);  %x方不同时刻的人数
            x(1)=x_init;        %初始化x方的人数
            y=zeros(test_num);  %y方不同时刻的人数
            y(1)=y_init;        %初始化y方的人数
            i_t=0;  %用来记录仿真结束时间点的变量
            
            % 战斗人数变化仿真
            for i=2:1:test_num
                dx=-a*x(i-1)-alpha*x(i-1)+u;
                dy=-b*y(i-1)-beta *y(i-1)+v;
                x(i)=x(i-1)+dx;
                y(i)=y(i-1)+dy;
                if abs(x(i)) <=1 || abs(y(i)) <=1
                    i_t=i-1;
                    break
                end
            end

            
            % 画图
            if i_t>test_num
                i_t=test_num;
            end
            
            x=x(1:i_t);
            y=y(1:i_t);
            time=1:i_t;
            cla(app.UIAxes)
            cla(app.UIAxes2)
            
            axis(app.UIAxes2,[0,i_t*1.2,0,max(x(1),y(1))*1.2]);
            axis(app.UIAxes,[0,x(1)*1.2,0,y(1)*1.2]);
            x1 = animatedline(app.UIAxes2,'Color','r');
            y1 = animatedline(app.UIAxes2,'Color','b');
            xy = animatedline(app.UIAxes,'Color','g');
            legend(app.UIAxes2,'X','Y')
            for i = 1:1:i_t
                addpoints(x1,i,x(i));
                addpoints(y1,i,y(i));
                addpoints(xy,x(i),y(i))
                drawnow
            end
            
            %plot(app.UIAxes2,time,x,time,y)


        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            
            %正规战的胜利方选择及其数据初始化
            
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'X胜利'
                    app.EditField_x_init.Value=10000; %x的初始士兵数
                    app.EditField_y_init.Value=5000;  %y的初始士兵数
                    app.TEditField.Value=1000;        %最大仿真次数
                    app.rxEditField.Value=100;        %x方射击率（每个士兵单位时间内的射击次数）
                    app.ryEditField.Value=10;        %y方射击率（每个士兵单位时间内的射击次数）
                    app.pxEditField.Value=0.001;      %x方的每次射击的命中率
                    app.pyEditField.Value=0.001;      %y方的每次射击的命中率
                    app.uEditField.Value=0;           %单位时间内x方的增援人数
                    app.vEditField.Value=0;           %单位时间内y方的增援人数
                    app.alphaEditField.Value=0.001;       %x方的非战斗减员率
                    app.betaEditField.Value=0.001;        %y方的非战斗减员率
                case 'Y胜利'
                    app.EditField_x_init.Value=10000;   %x的初始士兵数
                    app.EditField_y_init.Value=5000;  %y的初始士兵数
                    app.TEditField.Value=1000;         %最大仿真次数
                    app.rxEditField.Value=10;         %x方射击率（每个士兵单位时间内的射击次数）
                    app.ryEditField.Value=100;         %y方射击率（每个士兵单位时间内的射击次数）
                    app.pxEditField.Value=0.001;       %x方的每次射击的命中率
                    app.pyEditField.Value=0.001;       %y方的每次射击的命中率
                    app.uEditField.Value=0;            %单位时间内x方的增援人数
                    app.vEditField.Value=0;            %单位时间内y方的增援人数
                    app.alphaEditField.Value=0.001;        %x方的非战斗减员率
                    app.betaEditField.Value=0.001;         %y方的非战斗减员率
            end
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            
            %游击战的胜利方选择及其数据初始化
            
            selectedButton = app.ButtonGroup_2.SelectedObject;
            switch selectedButton.Text
                case 'X胜利'
                    app.EditField_x_init_2.Value=100; %x的初始士兵数
                    app.EditField_y_init_2.Value=50;  %y的初始士兵数
                    app.TEditField_2.Value=1000;        %最大仿真次数
                    app.rxEditField_2.Value=10;        %x方射击率（每个士兵单位时间内的射击次数）
                    app.ryEditField_2.Value=10;        %y方射击率（每个士兵单位时间内的射击次数）
                    app.srxEditField.Value=1;           %x方的有效射击面积
                    app.sryEditField.Value=1;           %y方的有效射击面积
                    app.sxEditField.Value=10000;       %x方的有效活动面积
                    app.syEditField.Value=5000;       %y方的有效活动面积
                    app.uEditField_2.Value=0;           %单位时间内x方的增援人数
                    app.vEditField_2.Value=0;           %单位时间内y方的增援人数
                    app.alphaEditField_2.Value=0;       %x方的非战斗减员率
                    app.betaEditField_2.Value=0;        %y方的非战斗减员率
                case 'Y胜利'
                    app.EditField_x_init_2.Value=100;   %x的初始士兵数
                    app.EditField_y_init_2.Value=50;  %y的初始士兵数
                    app.TEditField_2.Value=1000;         %最大仿真次数
                    app.rxEditField_2.Value=10;         %x方射击率（每个士兵单位时间内的射击次数）
                    app.ryEditField_2.Value=10;         %y方射击率（每个士兵单位时间内的射击次数）
                    app.srxEditField.Value=1;           %x方的有效射击面积
                    app.sryEditField.Value=1;           %y方的有效射击面积
                    app.sxEditField.Value=10000;       %x方的有效活动面积
                    app.syEditField.Value=100000;       %y方的有效活动面积
                    app.uEditField_2.Value=0;            %单位时间内x方的增援人数
                    app.vEditField_2.Value=0;            %单位时间内y方的增援人数
                    app.alphaEditField_2.Value=0;        %x方的非战斗减员率
                    app.betaEditField_2.Value=0;         %y方的非战斗减员率
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            % 参数定义
            x_init=app.EditField_x_init_2.Value; %x的初始士兵数
            y_init=app.EditField_y_init_2.Value;  %y的初始士兵数
            test_num=app.TEditField_2.Value;%最大仿真次数
            rx=app.rxEditField_2.Value;       %x方射击率（每个士兵单位时间内的射击次数）
            ry=app.ryEditField_2.Value;        %y方射击率（每个士兵单位时间内的射击次数）
            sry=app.sryEditField.Value;
            sx=app.sxEditField.Value;
            srx=app.srxEditField.Value;
            sy=app.syEditField.Value;
            px=srx/sy;
            py=sry/sx;
            c=ry*py;      %y方战斗有效系数
            d=rx*px;      %x方战斗有效系数
            u=app.uEditField_2.Value;          %单位时间内x方的增援人数
            v=app.vEditField_2.Value;          %单位时间内y方的增援人数
            alpha=app.alphaEditField_2.Value;  %x方的非战斗减员率
            beta=app.betaEditField_2.Value;   %y方的非战斗减员率
            dx=0;         %x方人数变化量
            dy=0;         %x方人数变化量
            x=zeros(test_num);  %x方不同时刻的人数
            x(1)=x_init;        %初始化x方的人数
            y=zeros(test_num);  %y方不同时刻的人数
            y(1)=y_init;        %初始化y方的人数
            i_t=0;  %用来记录仿真结束时间点的变量
            
            % 战斗人数变化仿真
            for i=2:1:test_num
                dx=-c*x(i-1)*y(i-1)-alpha*x(i-1)+u;
                dy=-d*y(i-1)*y(i-1)-beta *y(i-1)+v;
                x(i)=x(i-1)+dx;
                y(i)=y(i-1)+dy;
                if abs(x(i)) <=1 || abs(y(i)) <=1
                    i_t=i-1;
                    break
                end
            end

            
            % 画图
            if i_t>test_num
                i_t=test_num;
            end
            
            x=x(1:i_t);
            y=y(1:i_t);
            time=1:i_t;
            cla(app.UIAxes_2)
            cla(app.UIAxes2_2)
            
            axis(app.UIAxes2_2,[0,i_t*1.2,0,max(x(1),y(1))*1.2]);
            axis(app.UIAxes_2,[0,x(1)*1.2,0,y(1)*1.2]);
            x1 = animatedline(app.UIAxes2_2,'Color','r');
            y1 = animatedline(app.UIAxes2_2,'Color','b');
            xy = animatedline(app.UIAxes_2,'Color','g');
            legend(app.UIAxes2_2,'X','Y')
            for i = 1:1:i_t
                addpoints(x1,i,x(i));
                addpoints(y1,i,y(i));
                addpoints(xy,x(i),y(i))
                drawnow
            end
        end

        % Selection changed function: ButtonGroup_3
        function ButtonGroup_3SelectionChanged(app, event)
            
            %混合战模型（X为正规军，Y为游击队）
            
            selectedButton = app.ButtonGroup_3.SelectedObject;
            switch selectedButton.Text
                case 'X胜利'
                    app.EditField_x_init_3.Value=1000; %x的初始士兵数
                    app.EditField_y_init_3.Value=50;  %y的初始士兵数
                    app.TEditField_3.Value=1000;        %最大仿真次数
                    app.rxEditField_3.Value=20;       %x方射击率（每个士兵单位时间内的射击次数）
                    app.ryEditField_3.Value=10;        %y方射击率（每个士兵单位时间内的射击次数）
                    app.srxEditField_2.Value=1;   %x方有效射击面积
                    app.pyEditField_2.Value=0.001;      %y方的每次射击的命中率
                    app.syEditField_2.Value=100000;        %y方有效活动面积
                    app.uEditField_3.Value=0;           %单位时间内x方的增援人数
                    app.vEditField_3.Value=0;           %单位时间内y方的增援人数
                    app.alphaEditField_3.Value=0;       %x方的非战斗减员率
                    app.betaEditField_3.Value=0;        %y方的非战斗减员率
                case 'Y胜利'
                    app.EditField_x_init_3.Value=1000;   %x的初始士兵数
                    app.EditField_y_init_3.Value=500;  %y的初始士兵数
                    app.TEditField_3.Value=1000;         %最大仿真次数
                    app.rxEditField_3.Value=20;         %x方射击率（每个士兵单位时间内的射击次数）
                    app.ryEditField_3.Value=10;         %y方射击率（每个士兵单位时间内的射击次数）
                    app.srxEditField_2.Value=1;   %x方有效射击面积
                    app.pyEditField_2.Value=0.01;       %y方的每次射击的命中率
                    app.syEditField_2.Value=100000;         %y方有效活动面积
                    app.uEditField_3.Value=0;            %单位时间内x方的增援人数
                    app.vEditField_3.Value=0;            %单位时间内y方的增援人数
                    app.alphaEditField_3.Value=0;        %x方的非战斗减员率
                    app.betaEditField_3.Value=0;         %y方的非战斗减员率
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)

            %混合战
            
            % 参数定义
            x_init=app.EditField_x_init_3.Value; %x的初始士兵数
            y_init=app.EditField_y_init_3.Value;  %y的初始士兵数
            test_num=app.TEditField_3.Value;%最大仿真次数
            rx=app.rxEditField_3.Value;       %x方射击率（每个士兵单位时间内的射击次数）
            ry=app.ryEditField_3.Value;        %y方射击率（每个士兵单位时间内的射击次数）
            srx=app.srxEditField_2.Value;
            sy=app.syEditField_2.Value;
            px=srx/sy;     %x方的每次射击的命中率
            py=app.pyEditField_2.Value;     %y方的每次射击的命中率
            a=ry*py;      %y方战斗有效系数
            b=rx*px;      %x方战斗有效系数
            u=0;          %单位时间内x方的增援人数
            v=0;          %单位时间内y方的增援人数
            alpha=app.alphaEditField_3.Value;  %x方的非战斗减员率
            beta=app.betaEditField_3.Value;   %y方的非战斗减员率
            dx=0;         %x方人数变化量
            dy=0;         %x方人数变化量
            x=zeros(test_num);  %x方不同时刻的人数
            x(1)=x_init;        %初始化x方的人数
            y=zeros(test_num);  %y方不同时刻的人数
            y(1)=y_init;        %初始化y方的人数
            i_t=0;  %用来记录仿真结束时间点的变量
            
            % 战斗人数变化仿真
            for i=2:1:test_num
                dx=-a*x(i-1)-alpha*x(i-1)+u;
                dy=-b*y(i-1)*x(i-1)-beta *y(i-1)+v;
                x(i)=x(i-1)+dx;
                y(i)=y(i-1)+dy;
                if abs(x(i)) <=1 || abs(y(i)) <=1 || x(i)<0 || y(i)<0
                    i_t=i-1;
                    break
                end
            end
            
            % 画图
            if i_t>test_num
                i_t=test_num;
            end
            
            x=x(1:i_t);
            y=y(1:i_t);
            time=1:i_t;
            cla(app.UIAxes_3)
            cla(app.UIAxes2_3)
            
            axis(app.UIAxes2_3,[0,i_t*1.2,0,max(x(1),y(1))*1.2]);
            axis(app.UIAxes_3,[0,x(1)*1.2,0,y(1)*1.2]);
            x1 = animatedline(app.UIAxes2_3,'Color','r');
            y1 = animatedline(app.UIAxes2_3,'Color','b');
            xy = animatedline(app.UIAxes_3,'Color','g');
            legend(app.UIAxes2_3,'X','Y');
            for i = 1:1:i_t
                addpoints(x1,i,x(i));
                addpoints(y1,i,y(i));
                addpoints(xy,x(i),y(i))
                drawnow
            end


        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            % 参数定义
            x_init=73000; %x的初始士兵数
            y_init=21500;  %y的初始士兵数
            test_num=1000;%最大仿真次数
            a=0.0106;      %y方战斗有效系数
            b=0.0544;      %x方战斗有效系数
            u=0;          %单位时间内x方的增援人数
            v=0;          %单位时间内y方的增援人数
            alpha=0;  %x方的非战斗减员率
            beta=0;   %y方的非战斗减员率
            dx=0;         %x方人数变化量
            dy=0;         %x方人数变化量
            x=zeros(test_num);  %x方不同时刻的人数
            x(1)=x_init;        %初始化x方的人数
            y=zeros(test_num);  %y方不同时刻的人数
            y(1)=y_init;        %初始化y方的人数
            i_t=0;  %用来记录仿真结束时间点的变量
            
            % 战斗人数变化仿真
            for i=2:1:test_num
                dx=-a*x(i-1)-alpha*x(i-1)+u;
                dy=-b*y(i-1)-beta *y(i-1)+v;
                x(i)=x(i-1)+dx;
                y(i)=y(i-1)+dy;
                if abs(x(i)) <=1 || abs(y(i)) <=1
                    i_t=i-1;
                    break
                end
            end

            
            % 画图
            if i_t>test_num
                i_t=test_num;
            end
            
            x=x(1:i_t);
            y=y(1:i_t);
            time=1:i_t;
            
            plot(app.UIAxes5,time,x,time,y)
            legend(app.UIAxes5,'美军','日军')
            
            axis(app.UIAxes6,[0,x(1)*1.2,0,y(1)*1.2]);
            plot(app.UIAxes6,x,y,'g')


            size(x)
            %画图
            app.LHD(x,y);
            

        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            msgbox({'硫磺岛位于东京以南660英里的海面上,是日军的重要空军基地.美军在1945年2月19日开始进攻,激烈的战斗持续了一个月,双方伤亡惨重,日方守军21500人全部阵亡或被俘,美方投入兵力73000人,伤亡20265人.战争进行到28天时美军宣布占领该岛,实际战斗到36天才停止.美军的战地记录有按天统计的战斗减员和增援情况.日军没有后援,战地记录则全部遗失.' 
               ' ';'硫磺岛战役的数学模型为： ';'a=0.544' ; 'b=0.0106';'alpha=0';'beta=0';'美军初始人数为73000人，日军初始人数为21500人'},"关于硫磺岛战役和其数学模型")
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            % 参数定义
            x_init=1500; %x的初始士兵数
            y_init=3500;  %y的初始士兵数
            test_num=1000;%最大仿真次数
            a=0.01;      %y方战斗有效系数
            b=20/900000;      %x方战斗有效系数
            u=0;          %单位时间内x方的增援人数
            v=0;          %单位时间内y方的增援人数
            alpha=0;  %x方的非战斗减员率
            beta=0;   %y方的非战斗减员率
            dx=0;         %x方人数变化量
            dy=0;         %x方人数变化量
            x=zeros(test_num);  %x方不同时刻的人数
            x(1)=x_init;        %初始化x方的人数
            y=zeros(test_num);  %y方不同时刻的人数
            y(1)=y_init;        %初始化y方的人数
            i_t=0;  %用来记录仿真结束时间点的变量
            
            % 战斗人数变化仿真
            for i=2:1:test_num
                dx=-a*x(i-1)-alpha*x(i-1)+u;
                dy=-b*y(i-1)*x(i-1)-beta *y(i-1)+v;
                x(i)=x(i-1)+dx;
                y(i)=y(i-1)+dy;
                if abs(x(i)) <=1 || abs(y(i)) <=1
                    i_t=i-1;
                    break
                end
            end

            
            % 画图
            if i_t>test_num
                i_t=test_num;
            end
            
            x=x(1:i_t);
            y=y(1:i_t);
            time=1:i_t;
            
            plot(app.UIAxes5_2,time,x,time,y)
            legend(app.UIAxes5_2,'美军','越军')
            
            axis(app.UIAxes,[0,x(1)*1.2,0,y(1)*1.2]);
            plot(app.UIAxes6_2,x,y,'g')


            size(x)
            %画图
            app.YZ(x,y);
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            msgbox({'德浪河谷战役是美国介入越南战争以来，美军与越南人民军（北越军）主力部队第一次重大正面交战，双方都认为取得了很大的战果，都认为己方是胜利者。';
                '美军投入人数约为1500人，主要武器为24门M2_105mm轻型榴弹炮从A-1E螺旋桨对地攻击机至B-52重型轰炸机等各式空中支援.越军投入人数约为3500人，主要武器为AK47等步枪和其他苏式重机枪';
                '美军阵亡人数约为300人，越军阵亡人数约为1500人';' ';'此次战役的模型为：';'美军初始兵力为1500人';'越军初始兵力为3500人';'美军射击率为20';'越军射击率为10';'越军有效活动面积为900000'; ...
                '越军命中率为0.001';'双方增援人数均为0';'双方非战斗减员率均为0'}, "关于德浪河谷战役战役和其数学模型")
        end

        % Size changed function: Tab_4
        function Tab_4SizeChanged(app, event)
            position = app.Tab_4.Position;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_7 = uitab(app.TabGroup);
            app.Tab_7.Title = '欢迎界面';
            app.Image = uiimage(app.Tab_7);
            app.Image.ImageSource = 'face.png';
            app.Label_6 = uilabel(app.Tab_7);
            app.Label_6.Text = '战争模拟器';
            app.byLabel = uilabel(app.Tab_7);
            app.byLabel.Text = 'by  肖力文';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '正规战';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, '两方士兵个数相轨线')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.Tab);
            title(app.UIAxes2, '士兵个数随时间的变化')
            xlabel(app.UIAxes2, 't')
            ylabel(app.UIAxes2, '士兵个数')
            app.XPanel = uipanel(app.Tab);
            app.XPanel.Title = '参数定义 X';
            app.Label = uilabel(app.XPanel);
            app.Label.Text = '初始兵力';
            app.EditField_x_init = uieditfield(app.XPanel, 'numeric');
            app.EditField_x_init.Value = 10000;
            app.rxEditFieldLabel = uilabel(app.XPanel);
            app.rxEditFieldLabel.Text = '射击率rx';
            app.rxEditField = uieditfield(app.XPanel, 'numeric');
            app.rxEditField.Value = 100;
            app.pxEditFieldLabel = uilabel(app.XPanel);
            app.pxEditFieldLabel.Text = '命中率px';
            app.pxEditField = uieditfield(app.XPanel, 'numeric');
            app.pxEditField.Value = 0.001;
            app.uEditFieldLabel = uilabel(app.XPanel);
            app.uEditFieldLabel.Text = '单位时间增援人数u';
            app.uEditField = uieditfield(app.XPanel, 'numeric');
            app.alphaEditFieldLabel = uilabel(app.XPanel);
            app.alphaEditFieldLabel.Text = '非战斗减员率alpha';
            app.alphaEditField = uieditfield(app.XPanel, 'numeric');
            app.YPanel = uipanel(app.Tab);
            app.YPanel.Title = '参数定义 Y';
            app.EditField_5Label = uilabel(app.YPanel);
            app.EditField_5Label.Text = '初始兵力';
            app.EditField_y_init = uieditfield(app.YPanel, 'numeric');
            app.EditField_y_init.Value = 5000;
            app.ryEditFieldLabel = uilabel(app.YPanel);
            app.ryEditFieldLabel.Text = '射击率ry';
            app.ryEditField = uieditfield(app.YPanel, 'numeric');
            app.ryEditField.Value = 10;
            app.pyEditFieldLabel = uilabel(app.YPanel);
            app.pyEditFieldLabel.Text = '命中率py';
            app.pyEditField = uieditfield(app.YPanel, 'numeric');
            app.pyEditField.Value = 0.001;
            app.vEditFieldLabel = uilabel(app.YPanel);
            app.vEditFieldLabel.Text = '单位时间增援人数v';
            app.vEditField = uieditfield(app.YPanel, 'numeric');
            app.betaEditFieldLabel = uilabel(app.YPanel);
            app.betaEditFieldLabel.Text = '非战斗减员率beta';
            app.betaEditField = uieditfield(app.YPanel, 'numeric');
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '开始';
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = '模拟时间';
            app.TEditFieldLabel = uilabel(app.Panel);
            app.TEditFieldLabel.Text = '模拟时间T';
            app.TEditField = uieditfield(app.Panel, 'numeric');
            app.TEditField.Value = 1000;
            app.ButtonGroup = uibuttongroup(app.Tab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '模式';
            app.XButton = uiradiobutton(app.ButtonGroup);
            app.XButton.Text = 'X胜利';
            app.XButton.Value = true;
            app.YButton = uiradiobutton(app.ButtonGroup);
            app.YButton.Text = 'Y胜利';
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '自定义';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '游击战';
            app.UIAxes_2 = uiaxes(app.Tab_2);
            title(app.UIAxes_2, '两方士兵个数相轨线')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes2_2 = uiaxes(app.Tab_2);
            title(app.UIAxes2_2, '士兵个数随时间的变化')
            xlabel(app.UIAxes2_2, 't')
            ylabel(app.UIAxes2_2, '士兵个数')
            app.XPanel_2 = uipanel(app.Tab_2);
            app.XPanel_2.Title = '参数定义 X';
            app.Label_2 = uilabel(app.XPanel_2);
            app.Label_2.Text = '初始兵力';
            app.EditField_x_init_2 = uieditfield(app.XPanel_2, 'numeric');
            app.EditField_x_init_2.Value = 100;
            app.rxEditField_2Label = uilabel(app.XPanel_2);
            app.rxEditField_2Label.Text = '射击率rx';
            app.rxEditField_2 = uieditfield(app.XPanel_2, 'numeric');
            app.rxEditField_2.Value = 10;
            app.uEditField_2Label = uilabel(app.XPanel_2);
            app.uEditField_2Label.Text = '单位时间增援人数u';
            app.uEditField_2 = uieditfield(app.XPanel_2, 'numeric');
            app.alphaEditField_2Label = uilabel(app.XPanel_2);
            app.alphaEditField_2Label.Text = '非战斗减员率alpha';
            app.alphaEditField_2 = uieditfield(app.XPanel_2, 'numeric');
            app.srxEditFieldLabel = uilabel(app.XPanel_2);
            app.srxEditFieldLabel.Text = '射击有效面积srx';
            app.srxEditField = uieditfield(app.XPanel_2, 'numeric');
            app.srxEditField.Value = 1;
            app.sxEditFieldLabel = uilabel(app.XPanel_2);
            app.sxEditFieldLabel.Text = '有效活动面积sx';
            app.sxEditField = uieditfield(app.XPanel_2, 'numeric');
            app.sxEditField.Value = 10000;
            app.YPanel_2 = uipanel(app.Tab_2);
            app.YPanel_2.Title = '参数定义 Y';
            app.EditField_5Label_2 = uilabel(app.YPanel_2);
            app.EditField_5Label_2.Text = '初始兵力';
            app.EditField_y_init_2 = uieditfield(app.YPanel_2, 'numeric');
            app.EditField_y_init_2.Value = 50;
            app.ryEditField_2Label = uilabel(app.YPanel_2);
            app.ryEditField_2Label.Text = '射击率ry';
            app.ryEditField_2 = uieditfield(app.YPanel_2, 'numeric');
            app.ryEditField_2.Value = 10;
            app.vEditField_2Label = uilabel(app.YPanel_2);
            app.vEditField_2Label.Text = '单位时间增援人数v';
            app.vEditField_2 = uieditfield(app.YPanel_2, 'numeric');
            app.betaEditField_2Label = uilabel(app.YPanel_2);
            app.betaEditField_2Label.Text = '非战斗减员率beta';
            app.betaEditField_2 = uieditfield(app.YPanel_2, 'numeric');
            app.sryEditFieldLabel = uilabel(app.YPanel_2);
            app.sryEditFieldLabel.Text = '射击有效面积sry';
            app.sryEditField = uieditfield(app.YPanel_2, 'numeric');
            app.sryEditField.Value = 1;
            app.syEditFieldLabel = uilabel(app.YPanel_2);
            app.syEditFieldLabel.Text = '有效活动面积sy';
            app.syEditField = uieditfield(app.YPanel_2, 'numeric');
            app.syEditField.Value = 5000;
            app.Button_3 = uibutton(app.Tab_2, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '开始';
            app.Panel_2 = uipanel(app.Tab_2);
            app.Panel_2.Title = '模拟时间';
            app.TEditField_2Label = uilabel(app.Panel_2);
            app.TEditField_2Label.Text = '模拟时间T';
            app.TEditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.TEditField_2.Value = 1000;
            app.ButtonGroup_2 = uibuttongroup(app.Tab_2);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ButtonGroup_2.Title = '模式';
            app.XButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.XButton_2.Text = 'X胜利';
            app.XButton_2.Value = true;
            app.YButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.YButton_2.Text = 'Y胜利';
            app.Button_4 = uiradiobutton(app.ButtonGroup_2);
            app.Button_4.Text = '自定义';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '混合战';
            app.UIAxes_3 = uiaxes(app.Tab_3);
            title(app.UIAxes_3, '两方士兵个数相轨线')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes2_3 = uiaxes(app.Tab_3);
            title(app.UIAxes2_3, '士兵个数随时间的变化')
            xlabel(app.UIAxes2_3, 't')
            ylabel(app.UIAxes2_3, '士兵个数')
            app.YPanel_3 = uipanel(app.Tab_3);
            app.YPanel_3.Title = '参数定义 Y（游击队）';
            app.EditField_5Label_3 = uilabel(app.YPanel_3);
            app.EditField_5Label_3.Text = '初始兵力';
            app.EditField_y_init_3 = uieditfield(app.YPanel_3, 'numeric');
            app.EditField_y_init_3.Value = 50;
            app.ryEditField_3Label = uilabel(app.YPanel_3);
            app.ryEditField_3Label.Text = '射击率ry';
            app.ryEditField_3 = uieditfield(app.YPanel_3, 'numeric');
            app.ryEditField_3.Value = 10;
            app.vEditField_3Label = uilabel(app.YPanel_3);
            app.vEditField_3Label.Text = '单位时间增援人数v';
            app.vEditField_3 = uieditfield(app.YPanel_3, 'numeric');
            app.betaEditField_3Label = uilabel(app.YPanel_3);
            app.betaEditField_3Label.Text = '非战斗减员率beta';
            app.betaEditField_3 = uieditfield(app.YPanel_3, 'numeric');
            app.syEditField_2Label = uilabel(app.YPanel_3);
            app.syEditField_2Label.Text = '有效活动面积sy';
            app.syEditField_2 = uieditfield(app.YPanel_3, 'numeric');
            app.syEditField_2.Value = 100000;
            app.pyEditField_2Label = uilabel(app.YPanel_3);
            app.pyEditField_2Label.Text = '命中率py';
            app.pyEditField_2 = uieditfield(app.YPanel_3, 'numeric');
            app.pyEditField_2.Value = 0.001;
            app.Button_5 = uibutton(app.Tab_3, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '开始';
            app.Panel_3 = uipanel(app.Tab_3);
            app.Panel_3.Title = '模拟时间';
            app.TEditField_3Label = uilabel(app.Panel_3);
            app.TEditField_3Label.Text = '模拟时间T';
            app.TEditField_3 = uieditfield(app.Panel_3, 'numeric');
            app.TEditField_3.Value = 1000;
            app.ButtonGroup_3 = uibuttongroup(app.Tab_3);
            app.ButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_3SelectionChanged, true);
            app.ButtonGroup_3.Title = '模式';
            app.XButton_3 = uiradiobutton(app.ButtonGroup_3);
            app.XButton_3.Text = 'X胜利';
            app.XButton_3.Value = true;
            app.YButton_3 = uiradiobutton(app.ButtonGroup_3);
            app.YButton_3.Text = 'Y胜利';
            app.Button_6 = uiradiobutton(app.ButtonGroup_3);
            app.Button_6.Text = '自定义';
            app.XPanel_3 = uipanel(app.Tab_3);
            app.XPanel_3.Title = '参数定义 X（正规军）';
            app.Label_3 = uilabel(app.XPanel_3);
            app.Label_3.Text = '初始兵力';
            app.EditField_x_init_3 = uieditfield(app.XPanel_3, 'numeric');
            app.EditField_x_init_3.Value = 1000;
            app.rxEditField_3Label = uilabel(app.XPanel_3);
            app.rxEditField_3Label.Text = '射击率rx';
            app.rxEditField_3 = uieditfield(app.XPanel_3, 'numeric');
            app.rxEditField_3.Value = 20;
            app.uEditField_3Label = uilabel(app.XPanel_3);
            app.uEditField_3Label.Text = '单位时间增援人数u';
            app.uEditField_3 = uieditfield(app.XPanel_3, 'numeric');
            app.alphaEditField_3Label = uilabel(app.XPanel_3);
            app.alphaEditField_3Label.Text = '非战斗减员率alpha';
            app.alphaEditField_3 = uieditfield(app.XPanel_3, 'numeric');
            app.srxEditField_2Label = uilabel(app.XPanel_3);
            app.srxEditField_2Label.Text = '射击有效面积srx';
            app.srxEditField_2 = uieditfield(app.XPanel_3, 'numeric');
            app.srxEditField_2.Value = 1;
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.SizeChangedFcn = createCallbackFcn(app, @Tab_4SizeChanged, true);
            app.Tab_4.Title = '硫磺岛战役（正规战）';
            app.UIAxes4 = uiaxes(app.Tab_4);
            title(app.UIAxes4, '战局示意图')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.Button_7 = uibutton(app.Tab_4, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '开始模拟';
            app.UIAxes5 = uiaxes(app.Tab_4);
            title(app.UIAxes5, '士兵个数随时间的变化')
            xlabel(app.UIAxes5, 't')
            ylabel(app.UIAxes5, '士兵个数')
            app.UIAxes6 = uiaxes(app.Tab_4);
            title(app.UIAxes6, '两方士兵数量相轨线')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            app.EditField = uieditfield(app.Tab_4, 'text');
            app.Label_4 = uilabel(app.Tab_4);
            app.Label_4.Text = '硫磺岛战役战局模拟（正规战）';
            app.Button_8 = uibutton(app.Tab_4, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '点击查看关于此次战役的详细信息';
            app.TextArea_2 = uitextarea(app.Tab_4);
            app.TextArea_2.Value = {'蓝色为美军'; '黄色为日军'};
            app.Tab_6 = uitab(app.TabGroup);
            app.Tab_6.Title = '德浪河谷战役（混合战）';
            app.UIAxes4_2 = uiaxes(app.Tab_6);
            title(app.UIAxes4_2, '战局示意图')
            xlabel(app.UIAxes4_2, '')
            ylabel(app.UIAxes4_2, '')
            app.Button_9 = uibutton(app.Tab_6, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '开始模拟';
            app.UIAxes5_2 = uiaxes(app.Tab_6);
            title(app.UIAxes5_2, '士兵个数随时间的变化')
            xlabel(app.UIAxes5_2, 't')
            ylabel(app.UIAxes5_2, '士兵个数')
            app.UIAxes6_2 = uiaxes(app.Tab_6);
            title(app.UIAxes6_2, '两方士兵数量相轨线')
            xlabel(app.UIAxes6_2, 'X')
            ylabel(app.UIAxes6_2, 'Y')
            app.EditField_4 = uieditfield(app.Tab_6, 'text');
            app.Label_5 = uilabel(app.Tab_6);
            app.Label_5.Text = '德浪河谷战役战局模拟（混合战）';
            app.Button_10 = uibutton(app.Tab_6, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '点击查看关于此次战役的详细信息';
            app.TextArea = uitextarea(app.Tab_6);
            app.TextArea.Value = {'蓝色为美军（正规军）'; '红色为越军（游击队）'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = battle

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end